classdef AirsoftCalculationTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab_Menu                     matlab.ui.container.Tab
        Text_Author                  matlab.ui.control.Label
        Text_Version                 matlab.ui.control.Label
        Text_Title                   matlab.ui.control.Label
        Tab_Params                   matlab.ui.container.Tab
        Gauge_velocity               matlab.ui.control.LinearGauge
        KineticEnergyJLabel_2        matlab.ui.control.Label
        Lamp_SpinDecay               matlab.ui.control.Lamp
        Lamp_MagnusEffect            matlab.ui.control.Lamp
        Lamp_AirFriction             matlab.ui.control.Lamp
        Lamp_Gravity                 matlab.ui.control.Lamp
        Output_Spin                  matlab.ui.control.NumericEditField
        SpinrpmEditFieldLabel        matlab.ui.control.Label
        Knob_hopUp                   matlab.ui.control.Knob
        HopUpLabel                   matlab.ui.control.Label
        Spinner_energy               matlab.ui.control.Spinner
        Spinner_mass                 matlab.ui.control.Spinner
        Spinner_diameter             matlab.ui.control.Spinner
        ProjectileDiametermmLabel    matlab.ui.control.Label
        Output_velocity              matlab.ui.control.NumericEditField
        Spinner_angle                matlab.ui.control.Spinner
        Spinner_height               matlab.ui.control.Spinner
        Spinner_gravity              matlab.ui.control.Spinner
        Spinner_air_density          matlab.ui.control.Spinner
        AngledegLabel_4              matlab.ui.control.Label
        KineticEnergyJLabel          matlab.ui.control.Label
        AngledegLabel_3              matlab.ui.control.Label
        AngledegLabel_2              matlab.ui.control.Label
        AngledegLabel                matlab.ui.control.Label
        AngleIndicatorLabel          matlab.ui.control.Label
        Toggle_gravity               matlab.ui.control.ToggleSwitch
        Toggle_friction              matlab.ui.control.ToggleSwitch
        Toggle_magnus                matlab.ui.control.ToggleSwitch
        Toggle_spin_decay            matlab.ui.control.ToggleSwitch
        SpinDecaySwitchLabel         matlab.ui.control.Label
        MagnusEffectSwitchLabel      matlab.ui.control.Label
        AirFrictionSwitchLabel       matlab.ui.control.Label
        Gauge_energy                 matlab.ui.control.LinearGauge
        Gauge_angle                  matlab.ui.control.NinetyDegreeGauge
        GravitySwitchLabel           matlab.ui.control.Label
        ProjectilevelocitymsEditFieldLabel  matlab.ui.control.Label
        ProjectileMassgSpinnerLabel  matlab.ui.control.Label
        KineticEnergyJSpinnerLabel   matlab.ui.control.Label
        Tab_SimPlots                 matlab.ui.container.Tab
        EnergytPlotTab               matlab.ui.container.Tab
        xyPlotTab                    matlab.ui.container.Tab
        DPlotTab                     matlab.ui.container.Tab
        SpinPlotTab                  matlab.ui.container.Tab
        Tab5                         matlab.ui.container.Tab
        Tab6                         matlab.ui.container.Tab
        Tab_Help                     matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function InputValues(app)
            % Transfer the GUI Input fields into variables
            BB_Mass = app.Spinner_mass.Value/1000;              % [kg]
            BB_Diameter = app.Spinner_diameter.Value/1000;      % [m]
            BB_Energy_Initial = app.Spinner_energy.Value;       % [J]
            BB_Angle_Initial = app.Spinner_angle.Value;         % [°]
            BB_Shot_Height = app.Spinner_height.Value;          % [m]
            BB_Hop_Up      = app.Knob_hopUp.Value;              % [%]
            
            % Calculate initial conditions
            BB_Velocity_Initial = sqrt(2*BB_Energy_Initial/BB_Mass); % E=0,5*m*v2
            BB_Spin_Initial = BB_Hop_Up;

            % Update Output fields for Initial conditions
            app.Output_velocity.Value = BB_Velocity_Initial;
            app.Output_Spin.Value = BB_Spin_Initial;
            app.Gauge_angle.Value =BB_Angle_Initial;
            app.Gauge_energy.Value = BB_Energy_Initial;
            app.Gauge_velocity.Value = BB_Velocity_Initial;
        end
        
        function ToggleFunctions(app)
            Bool_Gravity = app.Toggle_gravity.Value;
            Bool_AirFriction = app.Toggle_friction.Value;
            Bool_MagnusEffect = app.Toggle_magnus.Value;
            Bool_SpinDecay = app.Toggle_spin_decay.Value;

            if(Bool_Gravity == 1)
                app.Lamp_Gravity.Color = [0.00,1.00,0.00];
            else
                app.Lamp_Gravity.Color = [0.65,0.65,0.65];
            end

            if(Bool_AirFriction == 1)
                app.Lamp_AirFriction.Color = [0.00,1.00,0.00];
            else
                app.Lamp_AirFriction.Color = [0.65,0.65,0.65];
            end

            if(Bool_MagnusEffect == 1)
                app.Lamp_MagnusEffect.Color = [0.00,1.00,0.00];
            else
                app.Lamp_MagnusEffect.Color = [0.65,0.65,0.65];
            end

            if(Bool_SpinDecay == 1)
                app.Lamp_SpinDecay.Color = [0.00,1.00,0.00];
            else
                app.Lamp_SpinDecay.Color = [0.65,0.65,0.65];
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            InputValues(app);
            ToggleFunctions(app);
        end

        % Value changed function: Knob_hopUp
        function Knob_hopUpValueChanged(app, event)
            value = app.Knob_hopUp.Value;
            InputValues(app);
        end

        % Value changing function: Knob_hopUp
        function Knob_hopUpValueChanging(app, event)
            changingValue = event.Value;
            InputValues(app);
        end

        % Value changed function: Spinner_energy
        function Spinner_energyValueChanged(app, event)
            value = app.Spinner_energy.Value;
            InputValues(app);
        end

        % Value changed function: Spinner_mass
        function Spinner_massValueChanged(app, event)
            value = app.Spinner_mass.Value;
            InputValues(app);
        end

        % Value changed function: Spinner_diameter
        function Spinner_diameterValueChanged(app, event)
            value = app.Spinner_diameter.Value;
            InputValues(app);
        end

        % Value changed function: Toggle_gravity
        function Toggle_gravityValueChanged(app, event)
            value = app.Toggle_gravity.Value;
            ToggleFunctions(app)
        end

        % Value changed function: Toggle_friction
        function Toggle_frictionValueChanged(app, event)
            value = app.Toggle_friction.Value;
            ToggleFunctions(app);
        end

        % Value changed function: Toggle_magnus
        function Toggle_magnusValueChanged(app, event)
            value = app.Toggle_magnus.Value;
            ToggleFunctions(app);
        end

        % Value changed function: Toggle_spin_decay
        function Toggle_spin_decayValueChanged(app, event)
            value = app.Toggle_spin_decay.Value;
            ToggleFunctions(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_Menu = uitab(app.TabGroup);
            app.Tab_Menu.Title = 'Menu';
            app.Text_Title = uilabel(app.Tab_Menu);
            app.Text_Title.Text = 'Airsoft Calculator';
            app.Text_Version = uilabel(app.Tab_Menu);
            app.Text_Version.Text = 'Version 0.3';
            app.Text_Author = uilabel(app.Tab_Menu);
            app.Text_Author.Text = 'P. Kruppe, M. Reichhoff, M. Goldschmidt';
            app.Tab_Params = uitab(app.TabGroup);
            app.Tab_Params.Title = 'Parameters';
            app.KineticEnergyJSpinnerLabel = uilabel(app.Tab_Params);
            app.KineticEnergyJSpinnerLabel.Text = 'Kinetic Energy [J]';
            app.ProjectileMassgSpinnerLabel = uilabel(app.Tab_Params);
            app.ProjectileMassgSpinnerLabel.Text = 'Projectile Mass [g]';
            app.ProjectilevelocitymsEditFieldLabel = uilabel(app.Tab_Params);
            app.ProjectilevelocitymsEditFieldLabel.Text = 'Projectile velocity [m/s]';
            app.GravitySwitchLabel = uilabel(app.Tab_Params);
            app.GravitySwitchLabel.Text = 'Gravity';
            app.Gauge_angle = uigauge(app.Tab_Params, 'ninetydegree');
            app.Gauge_angle.Limits = [0 90];
            app.Gauge_angle.ScaleDirection = 'counterclockwise';
            app.Gauge_angle.Value = 10;
            app.Gauge_energy = uigauge(app.Tab_Params, 'linear');
            app.Gauge_energy.Limits = [0 10];
            app.Gauge_energy.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Gauge_energy.Value = 2;
            app.AirFrictionSwitchLabel = uilabel(app.Tab_Params);
            app.AirFrictionSwitchLabel.Text = 'Air Friction';
            app.MagnusEffectSwitchLabel = uilabel(app.Tab_Params);
            app.MagnusEffectSwitchLabel.Text = 'Magnus Effect';
            app.SpinDecaySwitchLabel = uilabel(app.Tab_Params);
            app.SpinDecaySwitchLabel.Text = 'Spin Decay';
            app.Toggle_spin_decay = uiswitch(app.Tab_Params, 'toggle');
            app.Toggle_spin_decay.ItemsData = [0 1];
            app.Toggle_spin_decay.ValueChangedFcn = createCallbackFcn(app, @Toggle_spin_decayValueChanged, true);
            app.Toggle_spin_decay.Value = 1;
            app.Toggle_magnus = uiswitch(app.Tab_Params, 'toggle');
            app.Toggle_magnus.ItemsData = [0 1];
            app.Toggle_magnus.ValueChangedFcn = createCallbackFcn(app, @Toggle_magnusValueChanged, true);
            app.Toggle_magnus.Value = 1;
            app.Toggle_friction = uiswitch(app.Tab_Params, 'toggle');
            app.Toggle_friction.ItemsData = [0 1];
            app.Toggle_friction.ValueChangedFcn = createCallbackFcn(app, @Toggle_frictionValueChanged, true);
            app.Toggle_friction.Value = 1;
            app.Toggle_gravity = uiswitch(app.Tab_Params, 'toggle');
            app.Toggle_gravity.ItemsData = [0 1];
            app.Toggle_gravity.ValueChangedFcn = createCallbackFcn(app, @Toggle_gravityValueChanged, true);
            app.Toggle_gravity.Value = 1;
            app.AngleIndicatorLabel = uilabel(app.Tab_Params);
            app.AngleIndicatorLabel.Text = 'Angle Indicator [°]';
            app.AngledegLabel = uilabel(app.Tab_Params);
            app.AngledegLabel.Text = 'Angle [deg]';
            app.AngledegLabel_2 = uilabel(app.Tab_Params);
            app.AngledegLabel_2.Text = 'Height [m]';
            app.AngledegLabel_3 = uilabel(app.Tab_Params);
            app.AngledegLabel_3.Text = 'Gravity [m/s²]';
            app.KineticEnergyJLabel = uilabel(app.Tab_Params);
            app.KineticEnergyJLabel.Text = 'Kinetic Energy [J]';
            app.AngledegLabel_4 = uilabel(app.Tab_Params);
            app.AngledegLabel_4.Text = 'Air Density [kg/m³]';
            app.Spinner_air_density = uispinner(app.Tab_Params);
            app.Spinner_air_density.Limits = [0 Inf];
            app.Spinner_air_density.Tooltip = {'Typical Values:'; 'Earth: 1.24'; 'Moon: 0'; 'Venus: 8.23'; 'Mars: 0.54'};
            app.Spinner_air_density.Value = 1.24;
            app.Spinner_gravity = uispinner(app.Tab_Params);
            app.Spinner_gravity.Step = 0.5;
            app.Spinner_gravity.Limits = [0 Inf];
            app.Spinner_gravity.Tooltip = {'Suface gravity of the Simulation.'; 'Moon: 1.7'; 'Mars: 3.2'};
            app.Spinner_gravity.Value = 9.81;
            app.Spinner_height = uispinner(app.Tab_Params);
            app.Spinner_height.Step = 0.1;
            app.Spinner_height.Limits = [0 Inf];
            app.Spinner_height.Tooltip = {'The Height from which the gun is shot. The ground in this simulaiton is perfectly flat.'};
            app.Spinner_height.Value = 1.5;
            app.Spinner_angle = uispinner(app.Tab_Params);
            app.Spinner_angle.Limits = [0 90];
            app.Spinner_angle.Tooltip = {'Angle at witch the projectile is shot. 0° is parallel to the ground. 90° is straight vertical.'};
            app.Spinner_angle.Value = 10;
            app.Output_velocity = uieditfield(app.Tab_Params, 'numeric');
            app.Output_velocity.Editable = 'off';
            app.Output_velocity.Tooltip = {'Velocity of the Projectile depending on the previous two variables.'};
            app.ProjectileDiametermmLabel = uilabel(app.Tab_Params);
            app.ProjectileDiametermmLabel.Text = 'Projectile Diameter [mm]';
            app.Spinner_diameter = uispinner(app.Tab_Params);
            app.Spinner_diameter.Step = 0.1;
            app.Spinner_diameter.Limits = [0 Inf];
            app.Spinner_diameter.ValueChangedFcn = createCallbackFcn(app, @Spinner_diameterValueChanged, true);
            app.Spinner_diameter.Tooltip = {'The mass of the BB Projectile.'; 'Usually between 0.2 - 0.6 grams'};
            app.Spinner_diameter.Value = 0.3;
            app.Spinner_mass = uispinner(app.Tab_Params);
            app.Spinner_mass.Step = 0.1;
            app.Spinner_mass.Limits = [0 Inf];
            app.Spinner_mass.ValueChangedFcn = createCallbackFcn(app, @Spinner_massValueChanged, true);
            app.Spinner_mass.Tooltip = {'The mass of the BB Projectile.'; 'Usually between 0.2 - 0.6 grams'};
            app.Spinner_mass.Value = 0.3;
            app.Spinner_energy = uispinner(app.Tab_Params);
            app.Spinner_energy.Limits = [0 Inf];
            app.Spinner_energy.ValueChangedFcn = createCallbackFcn(app, @Spinner_energyValueChanged, true);
            app.Spinner_energy.Tooltip = {'The Kinetic Energy of the projectile when it leaves the barrel'};
            app.Spinner_energy.Value = 0.2;
            app.HopUpLabel = uilabel(app.Tab_Params);
            app.HopUpLabel.Text = 'Hop Up [%]';
            app.Knob_hopUp = uiknob(app.Tab_Params, 'continuous');
            app.Knob_hopUp.ValueChangedFcn = createCallbackFcn(app, @Knob_hopUpValueChanged, true);
            app.Knob_hopUp.ValueChangingFcn = createCallbackFcn(app, @Knob_hopUpValueChanging, true);
            app.SpinrpmEditFieldLabel = uilabel(app.Tab_Params);
            app.SpinrpmEditFieldLabel.Text = 'Spin [rpm]';
            app.Output_Spin = uieditfield(app.Tab_Params, 'numeric');
            app.Output_Spin.Editable = 'off';
            app.Lamp_Gravity = uilamp(app.Tab_Params);
            app.Lamp_AirFriction = uilamp(app.Tab_Params);
            app.Lamp_MagnusEffect = uilamp(app.Tab_Params);
            app.Lamp_SpinDecay = uilamp(app.Tab_Params);
            app.KineticEnergyJLabel_2 = uilabel(app.Tab_Params);
            app.KineticEnergyJLabel_2.Text = 'Projectile Speed [m/s]';
            app.Gauge_velocity = uigauge(app.Tab_Params, 'linear');
            app.Gauge_velocity.Limits = [0 1000];
            app.Gauge_velocity.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.Gauge_velocity.Value = 2;
            app.Tab_SimPlots = uitab(app.TabGroup);
            app.Tab_SimPlots.Title = 'Simulation Plot Overview';
            app.EnergytPlotTab = uitab(app.TabGroup);
            app.EnergytPlotTab.Title = 'Energy-t Plot';
            app.xyPlotTab = uitab(app.TabGroup);
            app.xyPlotTab.Title = 'x-y Plot';
            app.DPlotTab = uitab(app.TabGroup);
            app.DPlotTab.Title = '3D-Plot';
            app.SpinPlotTab = uitab(app.TabGroup);
            app.SpinPlotTab.Title = 'Spin Plot';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            app.Tab_Help = uitab(app.TabGroup);
            app.Tab_Help.Title = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AirsoftCalculationTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end